classdef OmniSTORM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OmniSTORMUIFigure               matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ParameterSetupPanel             matlab.ui.container.Panel
        EndEditField                    matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        StartEditField                  matlab.ui.control.NumericEditField
        StartEditFieldLabel             matlab.ui.control.Label
        ProcessFrameRangeCheckBox       matlab.ui.control.CheckBox
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        PixelSizenmEditField            matlab.ui.control.NumericEditField
        PixelSizenmEditFieldLabel       matlab.ui.control.Label
        ResolutionnmEditField           matlab.ui.control.NumericEditField
        ResolutionnmLabel               matlab.ui.control.Label
        ResolutionpxEditField           matlab.ui.control.NumericEditField
        ResolutionpxLabel               matlab.ui.control.Label
        EstimateInputResolutionCheckBox  matlab.ui.control.CheckBox
        LoadConfigButton                matlab.ui.control.Button
        SaveConfigButton                matlab.ui.control.Button
        PostProcessingPanel             matlab.ui.container.Panel
        AutoAdjustContrastCheckBox      matlab.ui.control.CheckBox
        ProcessingPanel                 matlab.ui.container.Panel
        UseGPUCheckBox                  matlab.ui.control.CheckBox
        ScaleEditField                  matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        ProcessingMethodTabGroup        matlab.ui.container.TabGroup
        LocalizationTab                 matlab.ui.container.Tab
        AIMTrackIntervalEditField       matlab.ui.control.NumericEditField
        AIMTrackIntervalEditFieldLabel  matlab.ui.control.Label
        AIMDriftCorrectionCheckBox      matlab.ui.control.CheckBox
        LocalizationDropDown            matlab.ui.control.DropDown
        LocalizationDropDownLabel       matlab.ui.control.Label
        DeconvolutionTab                matlab.ui.container.Tab
        InterpolationDropDown           matlab.ui.control.DropDown
        InterpolationDropDownLabel      matlab.ui.control.Label
        TemporalAnalysisDropDown        matlab.ui.control.DropDown
        TemporalAnalysisDropDownLabel   matlab.ui.control.Label
        PreProcessingPanel              matlab.ui.container.Panel
        SaveEstimatedBackgroundCheckBox  matlab.ui.control.CheckBox
        BackgroundRemovalDropDown       matlab.ui.control.DropDown
        BackgroundRemovalDropDownLabel  matlab.ui.control.Label
        ConvertIntensitytoPhotonCountCheckBox  matlab.ui.control.CheckBox
        InterFrameDifferenceCheckBox    matlab.ui.control.CheckBox
        RightPanel                      matlab.ui.container.Panel
        TIFFCheckBox                    matlab.ui.control.CheckBox
        DCIMGCheckBox                   matlab.ui.control.CheckBox
        ImageFileTypeLabel              matlab.ui.control.Label
        OpenResultFolderButton          matlab.ui.control.Button
        BrowseResultFolderButton        matlab.ui.control.Button
        OutputFolderEditField           matlab.ui.control.EditField
        OutputFolderEditFieldLabel      matlab.ui.control.Label
        LoadMultiFileImageButton        matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        BatchFolderButton               matlab.ui.control.Button
        LoadImageButton                 matlab.ui.control.Button
        ImageDisplayTabGroup            matlab.ui.container.TabGroup
        InputImageStackTab              matlab.ui.container.Tab
        InformationPanel                matlab.ui.container.Panel
        OffsetEditField                 matlab.ui.control.NumericEditField
        OffsetEditFieldLabel            matlab.ui.control.Label
        GainEditField                   matlab.ui.control.NumericEditField
        GainEditFieldLabel              matlab.ui.control.Label
        EstimateEmitterIntensityThresholdCheckBox  matlab.ui.control.CheckBox
        EstimateGainOffsetCheckBox      matlab.ui.control.CheckBox
        ContrastButtonInput             matlab.ui.control.Button
        TostartclickLoadImageorLoadMultiFileImageLabel  matlab.ui.control.Label
        InputImageParamPanel            matlab.ui.container.Panel
        FrameSlider                     matlab.ui.control.Slider
        FrameSliderLabel                matlab.ui.control.Label
        FrameSpinner                    matlab.ui.control.Spinner
        InputImageAxes                  matlab.ui.control.UIAxes
        SuperResolutionImageTab         matlab.ui.container.Tab
        SRImageParamPanel               matlab.ui.container.Panel
        ContrastButtonSR                matlab.ui.control.Button
        SaveImageButton                 matlab.ui.control.Button
        SRImageAxes                     matlab.ui.control.UIAxes
        LocalizationsTab                matlab.ui.container.Tab
        LocalizationsStatsPanel         matlab.ui.container.Panel
        LocalizationNumberEditField     matlab.ui.control.NumericEditField
        LocalizationNumberEditFieldLabel  matlab.ui.control.Label
        ClusterOperationsPanel          matlab.ui.container.Panel
        RunClusteringButton             matlab.ui.control.Button
        minptsEditField                 matlab.ui.control.NumericEditField
        minptsEditFieldLabel            matlab.ui.control.Label
        epsilonEditField                matlab.ui.control.NumericEditField
        epsilonEditFieldLabel           matlab.ui.control.Label
        ClusterScatterPlotAxes          matlab.ui.control.UIAxes
        FRCTab                          matlab.ui.container.Tab
        FRCInfoPanel                    matlab.ui.container.Panel
        FRCRunButton                    matlab.ui.control.Button
        FRCResolutionnmEditField        matlab.ui.control.NumericEditField
        EstimatedResolutionpxEditField_2Label_2  matlab.ui.control.Label
        FRCResolutionpxEditField        matlab.ui.control.NumericEditField
        EstimatedResolutionpxEditField_2Label  matlab.ui.control.Label
        FRCCurveAxes                    matlab.ui.control.UIAxes
        FRCImage2Axes                   matlab.ui.control.UIAxes
        FRCImage1Axes                   matlab.ui.control.UIAxes
        DriftTab                        matlab.ui.container.Tab
        DriftAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        % IO V2.0
        % Do not load all frames to memory
        % Only load a single frame for preview
        previewInputImageFrame

        % Used to store config temporarily.
        % Can be out-of-date. Use app.UI2Config() to get up-to-date config.
        config

        % Super-resolution result image
        processedImage

        % Struct to save localizations
        % Fields: X, Y, F, intensity
        localizations = struct( ...
            'X', [], ...
            'Y', [], ...
            'F', [], ...
            'intensity', [] ...
        );
        drift
        clusterId

        imageLoader;
        resultFolder

        % Remember the last opened file path,
        % when user open image again, start from lastOpenedPath
        lastOpenedPath
    end

    
    methods (Access = private)
        %% Image Read

        % Post process after loading image
        % Assumes app.imageLoader.openImage() or openImageFolder() is called
        function LoadImagePostProcess(app)
            % Display image info
            filename = app.imageLoader.imagePathList(1);
            if numel(app.imageLoader.imagePathList) > 1
                filename = filename + " and more";
            end

            % Set default result folder
            app.SetDefaultResultFolder();

            width = app.imageLoader.width;
            height = app.imageLoader.height;
            n_frames = app.imageLoader.totalFrames;
            sizeStr = sprintf(' (%d x %d x %d)', width, height, n_frames);
            app.TostartclickLoadImageorLoadMultiFileImageLabel.Text = strcat(filename, sizeStr);

            % Update frame control and display preview frame
            app.ResetFrameControl();
            app.ReadAndDrawInputFrame();
            app.ImageDisplayTabGroup.SelectedTab = app.InputImageStackTab;

            % Use the 1st frame to estimate Params (resolution, gain/offset, threshold)
            conf = app.UI2Config();
            conf.params = EstimateParams(app.previewInputImageFrame, conf);
            app.Config2UI(conf);

            app.UpdateInterFrameDifferenceCheckBox();
            app.EnableRunButton();
        end

        function SetDefaultResultFolder(app)
            % Display image info
            filename = app.imageLoader.imagePathList(1);
            [folder, name, ext] = fileparts(filename);

            % If opened a multi-file image stack, we don't include the
            % filename in the result path
            if numel(app.imageLoader.imagePathList) > 1
                name = '';
            end

            defaultFolder = GetDatetimeNamedDir(folder, name);
            app.UpdateResultFolder(defaultFolder);
            app.OutputFolderEditField.Value = defaultFolder;
        end

        function ReadAndDrawInputFrame(app)
            frame_id = floor(app.FrameSpinner.Value);
            app.previewInputImageFrame = app.imageLoader.readFrameRange(frame_id, 1);
            imagesc(app.InputImageAxes, app.previewInputImageFrame);
        end


        function DisableFrameControl(app)
            app.FrameSlider.Enable = "off";
            app.FrameSpinner.Enable = "off";
        end

        function EnableFrameControl(app)
            app.FrameSlider.Enable = "on";
            app.FrameSpinner.Enable = "on";
        end

        % Update frame slider
        function ResetFrameControl(app)
            n_frames = app.imageLoader.totalFrames;

            if n_frames == 1
                app.DisableFrameControl()
                app.FrameSlider.Value = 1;
                app.FrameSlider.Limits = [1 2];
                app.FrameSpinner.Value = 1;
                app.FrameSpinner.Limits = [1 n_frames];
            elseif n_frames > 1
                app.FrameSlider.Limits = [1 n_frames];
                app.FrameSlider.Value = 1;
    
                app.FrameSpinner.Limits = [1 n_frames];
                app.FrameSpinner.Value = 1;
                app.EnableFrameControl()
            end
        end

        function UpdateInterFrameDifferenceCheckBox(app)
            nFrames = app.imageLoader.totalFrames;
            if nFrames > 100
                app.InterFrameDifferenceCheckBox.Value = false;
                app.InterFrameDifferenceCheckBox.Enable = "off";
            else
                app.InterFrameDifferenceCheckBox.Enable = "on";
            end
        end

        function ErrorPopUp(app, msg, title)
            errordlg(msg, title)
        end

        function SaveLocalizationsCSV(app, filename)
            % In this app, the x-axis runs top down,
            % y-axis runs left-right,
            % which is opposite to many applications

            locTable = table;
            locTable.X = app.localizations.Y * app.PixelSizenmEditField.Value;
            locTable.Y = app.localizations.X * app.PixelSizenmEditField.Value;
            locTable.frameId = app.localizations.F;
            locTable.intensity = app.localizations.intensity;
            writetable(locTable, filename);
        end

        function SaveLocalizationsMat(app, filename)
            % app.localizations.X / Y are drift corrected values
            Xd = app.localizations.X;
            Yd = app.localizations.Y;
            F = app.localizations.F;
            drift = app.drift;

            % disp(size(drift));
            % disp(size(F));
            % disp(max(F));

            if numel(drift) > 0
                % Add drift back to get raw (non-drift-corrected) coordinates
                driftX = drift(F, 1); % Drift in X for each localization
                driftY = drift(F, 2); % Drift in Y for each localization
            
                X = Xd + driftX;
                Y = Yd + driftY;
            else
                X = Xd;
                Y = Yd;
            end

            save(filename, 'X','Y','F','drift','Xd','Yd')
        end

        function SaveLocalizationClusters(app, filename)
            locTable = table;
            locTable.X = app.localizations.Y * app.PixelSizenmEditField.Value;
            locTable.Y = app.localizations.X * app.PixelSizenmEditField.Value;
            locTable.frameId = app.localizations.F;
            locTable.intensity = app.localizations.intensity;
            locTable.clusterId = app.clusterId;
            writetable(locTable, filename);
        end

        function SaveLocalizationDrift(app, filename)
            locTable = table;
            locTable.frameId = (1:size(app.drift, 1))';
            locTable.driftX = app.drift(:, 1);
            locTable.driftY = app.drift(:, 2);

            writetable(locTable, filename);
        end

        %% SR Pipeline
        % When user clicks "Run" button, an SR pipeline is launched.

        function RunSuperResolutionPipeline(app)
            try
                app.CheckAppConfig();
            catch e
                app.ErrorPopUp(e.message, e.identifier);
                return
            end

            SaveConfigToHomeDir(app.config);

            app.CreateResultFolder();

            tic

            pipeline = OmniPipeline(app.imageLoader, app.config);
            totalSteps = pipeline.estimatedTotalSteps;
            stepCnt = 0;
            
            progressBar = uiprogressdlg(app.OmniSTORMUIFigure, ...
                "Title", "Running super-reolution pipeline", ...
                "Cancelable", "on");

            while ~pipeline.isFinished
                if progressBar.CancelRequested
                    break
                end

                pipeline.step();

                progressBar.Value = stepCnt / totalSteps;
                progressBar.Message = pipeline.stepMessage;
                stepCnt = stepCnt + 1;
            end
            
            if pipeline.isFinished
                progressBar.Message = "Saving results";
                app.SavePipelineResult(pipeline, app.resultFolder);
                progressBar.Message = "Updating UI";
                app.UpdateResultUI();
            end

            close(progressBar);

            elapsedTime = toc;
            message = sprintf('Super-resolution processing completed. Elapsed time: %.2f seconds', elapsedTime);
            msgbox(message, 'Completed');
        end

        % Check config
        function CheckAppConfig(app)
            app.config = app.UI2Config();
            ConfigSanityCheck(app.imageLoader.totalFrames, app.config);
        end

        function SavePipelineResult(app, pipeline, saveFolder)
            [app.processedImage, ...
             app.localizations.X, ...
             app.localizations.Y, ...
             app.localizations.F, ...
             app.localizations.intensity, ...
             app.drift, ...
             background] = pipeline.fetchResult();

            configSavePath = fullfile(saveFolder, 'config.json');
            writestruct(app.config, configSavePath, PrettyPrint=true);

            localizationSavePath = fullfile(saveFolder, 'localizations.csv');
            app.SaveLocalizationsCSV(localizationSavePath);

            matSavePath = fullfile(saveFolder, 'localizations.mat');
            app.SaveLocalizationsMat(matSavePath);

            SRImagePath = fullfile(saveFolder, 'super-resolution-image.tif');
            save_tiff(app.processedImage, SRImagePath);

            backgroundPath = fullfile(saveFolder, 'background.tif');
            backgroundSubtractedImgPath = fullfile(saveFolder, 'background-subtracted-image.tif');
            if numel(background) > 0
                [h, w, f] = size(background);
                maxSizeBytes = 4 * 1024^3; % 4 GB
                bytesPerFrame = h * w * 2; % uint16 = 2 bytes per pixel
                maxFrames = min(floor(maxSizeBytes / bytesPerFrame), f);
                background = background(:, :, 1:maxFrames);
                background = uint16(background);
                
                save_tiff(background, backgroundPath);
                rawImage = pipeline.imageLoader.readFrameRange(1, maxFrames);
                bg_substracted_image = uint16(rawImage) - background;
                
                save_tiff(bg_substracted_image, backgroundSubtractedImgPath);

            end

            if app.config.process.sparse.aim
                if ~exist(fullfile(saveFolder, 'Drift'), 'dir')
                    mkdir(fullfile(saveFolder, 'Drift'))
                end
                exportgraphics(app.DriftAxes, fullfile(saveFolder, 'Drift', 'drift-curve.png'));
                app.SaveLocalizationDrift(fullfile(saveFolder, 'Drift', 'drift.csv'));
            end
        end

        function UpdateResultUI(app)
            % Draw postprocessed image
            imagesc(app.SRImageAxes, app.processedImage);
            app.UpdateLocalizationPanel();

            % Draw drift UI
            app.UpdateDriftPanel();

            app.ImageDisplayTabGroup.SelectedTab = app.SuperResolutionImageTab;
        end

        %% FRC pipeline
        function RunFRC(app)
            try
                app.CheckAppConfig();
            catch e
                app.ErrorPopUp(e.message, e.identifier);
                return
            end

            SaveConfigToHomeDir(app.config);

            app.CreateResultFolder();

            if app.imageLoader.width ~= app.imageLoader.height
                uialert(app.OmniSTORMUIFigure, 'FRC only works on square image. Your image will be cropped.', 'FRC Warning');
            end

            frcDir = fullfile(app.resultFolder, 'FRC');
            if ~exist(frcDir, 'dir')
                mkdir(frcDir);
            end


            % Try to reuse localizations first
            if app.config.process.method == "Localization"
                if numel(app.localizations.X) <= 0
                    uialert("FRC Failed: Number of localizations is less than or equal to 0");
                    return
                end
                app.FRCRunButton.Enable = "off";
                drawnow nocallbacks;
                [oddImage, evenImage] = app.RunFRCFromLocalizations();
                app.UpdateFRCResultUI(oddImage, evenImage);
                app.FRCRunButton.Enable = "on";

            % If no localizations available, run FRC from scratch
            else
                [oddPipeline, evenPipeline] = app.RunFRCFromScratch();
                if oddPipeline.isFinished && evenPipeline.isFinished
                    [oddImage, ~, ~, ~, ~, ~] = oddPipeline.fetchResult();
                    [evenImage, ~, ~, ~, ~, ~] = evenPipeline.fetchResult();
                    app.UpdateFRCResultUI(oddImage, evenImage);
                end
            end
        end

        function [oddImage, evenImage] = RunFRCFromLocalizations(app)
            [oddFrames, evenFrames] = SplitLocalizationsForFRC(app.localizations);
            oddImage = RenderLocalizationToImage(oddFrames.X, ...
                                                 oddFrames.Y, ...
                                                 oddFrames.F, ...
                                                 oddFrames.intensity, ...
                                                 app.imageLoader.height, ...
                                                 app.imageLoader.width, ...
                                                 app.config);
            evenImage = RenderLocalizationToImage(evenFrames.X, ...
                                                  evenFrames.Y, ...
                                                  evenFrames.F, ...
                                                  evenFrames.intensity, ...
                                                  app.imageLoader.height, ...
                                                  app.imageLoader.width, ...
                                                  app.config);
        end

        function [oddPipeline, evenPipeline] = RunFRCFromScratch(app)
            % Split input image stack into even and odd frames
            [oddLoader, evenLoader] = SplitOddEvenFrames(app.imageLoader, fullfile(app.resultFolder, 'FRC'));

            oddPipeline = OmniPipeline(oddLoader, app.config);
            evenPipeline = OmniPipeline(evenLoader, app.config);

            totalSteps = oddPipeline.estimatedTotalSteps + evenPipeline.estimatedTotalSteps;
            stepCnt = 0;
            
            progressBar = uiprogressdlg(app.OmniSTORMUIFigure, ...
                "Title", "Running FRC", ...
                "Cancelable", "on");

            while ~oddPipeline.isFinished
                if progressBar.CancelRequested
                    break
                end

                oddPipeline.step();

                progressBar.Value = stepCnt / totalSteps;
                progressBar.Message = oddPipeline.stepMessage;
                stepCnt = stepCnt + 1;
            end

            while ~evenPipeline.isFinished
                if progressBar.CancelRequested
                    break
                end

                evenPipeline.step();

                progressBar.Value = stepCnt / totalSteps;
                progressBar.Message = evenPipeline.stepMessage;
                stepCnt = stepCnt + 1;
            end

            close(progressBar);
            
        end

        function UpdateFRCResultUI(app, oddImage, evenImage)
            if size(oddImage, 1) ~= size(oddImage, 2)
                oddImage = CenterCropToSquare(oddImage);
                evenImage = CenterCropToSquare(evenImage);
            end


            imagesc(app.FRCImage1Axes, oddImage);
            imagesc(app.FRCImage2Axes, evenImage);

            oddImage = single(oddImage);
            evenImage = single(evenImage);

            % Plot FRC curve
            frcParams.beta = 7;
            frc = FSC(oddImage, evenImage, frcParams);
            smoothedFRC = smooth(smooth(frc.frc));
            cla(app.FRCCurveAxes);
            hold(app.FRCCurveAxes, 'on');
            plot(app.FRCCurveAxes, frc.nu, smoothedFRC, 'DisplayName', 'FRC');
            plot(app.FRCCurveAxes, frc.nu, frc.T_hbit, 'DisplayName', '1/2 bit Threshold');
            legend(app.FRCCurveAxes, 'show');
            hold(app.FRCCurveAxes, 'off');

            % Calculate Resolution
            resolution = 0;
            for i = 1 : size(smoothedFRC, 1)
                if smoothedFRC(i) < frc.T_hbit(i)
                    resolution = 1 / frc.nu(i);
                    break
                end
            end

            app.FRCResolutionpxEditField.Value = resolution;
            app.FRCResolutionnmEditField.Value = resolution * app.config.params.pixelSize / app.config.process.scale;

            % Save results
            save_tiff(oddImage, fullfile(app.resultFolder, 'FRC', 'super-resolution-odd-frames.tif'));
            save_tiff(evenImage, fullfile(app.resultFolder, 'FRC', 'super-resolution-even-frames.tif'));
            exportgraphics(app.FRCCurveAxes, fullfile(app.resultFolder, 'FRC', 'frc-curve.png'));
        end


        %% Buttons

        function EnableRunButton(app)
            app.RunButton.Text = "Run";

            if app.imageLoader.totalFrames > 0
                app.RunButton.Enable = "on";
                app.FRCRunButton.Enable = "on";
            else
                app.RunButton.Enable = "off";
                app.FRCRunButton.Enable = "off";
            end

            app.BatchFolderButton.Enable = "on";
        end

        function DisableRunButton(app)
            app.RunButton.Enable = "off";

            app.BatchFolderButton.Enable = "off";
        end

        %% Batch Folder
        function LaunchBatchFolder(app, folderPath)
            % disp('launch batch folder begin');
            app.config = app.UI2Config();

            progressBar = uiprogressdlg(app.OmniSTORMUIFigure, ...
                "Title", "Reading files and checking config");
            
            files = dir(folderPath);
            n_subfolders = 0;
            inputSubFolderList = string([]);
            outputSubFolderList = string([]);
            pipelineList = OmniPipeline.empty;
            totalSteps = 0;
            for i = 1 : length(files)
                if ~files(i).isdir
                    continue;
                end
                if strcmp(files(i).name, '.') || strcmp(files(i).name, '..')
                    continue;
                end
                
                subFolder = fullfile(folderPath, files(i).name);
                outputSubFolder = GetDatetimeNamedDir(subFolder, "");

                n_subfolders = n_subfolders + 1;
                inputSubFolderList(n_subfolders) = string(subFolder);
                outputSubFolderList(n_subfolders) = string(outputSubFolder);

                loader = OmniImageLoader();
                ext_name_list = GetImageFileExtName(app.config);
                loader.openImageFolder(subFolder, ext_name_list);
                pipelineList(n_subfolders) = OmniPipeline(loader, app.config);
                totalSteps = totalSteps + pipelineList(n_subfolders).estimatedTotalSteps;


                try
                    ConfigSanityCheck(loader.totalFrames, app.config);
                catch e
                    app.ErrorPopUp(['Error in ' subFolder e.message], e.identifier);
                    return
                end
            end

            close(progressBar);

            SaveConfigToHomeDir(app.config);

            if n_subfolders == 0
                return
            end
            
            stepCnt = 0;
            progressBar = uiprogressdlg(app.OmniSTORMUIFigure, ...
                "Title", "Running Batch Folder", ...
                "Cancelable", "on");
            
            for i = 1 : n_subfolders
                if progressBar.CancelRequested
                    break
                end

                pipeline = pipelineList(i);
                
                while ~pipeline.isFinished
                    if progressBar.CancelRequested
                        break
                    end

                    pipeline.step();
    
                    progressBar.Value = stepCnt / totalSteps;
                    progressBar.Message = pipeline.stepMessage;
                    stepCnt = stepCnt + 1;
                end

                if pipelineList(i).isFinished
                    outputSubFolder = outputSubFolderList(i);
                    if ~exist(outputSubFolder, "dir")
                        mkdir(outputSubFolder)
                    end
                    app.SavePipelineResult(pipeline, outputSubFolder);
                end
            end

            close(progressBar);
            
            
            uialert(app.OmniSTORMUIFigure, "Batch folder completed." + newline + "Results are saved in the input folders.", "Notice");
        end

        %% Config Load/Save
        function Config2UI(app, config)
            app.EstimateInputResolutionCheckBox.Value = config.params.decorr;
            app.PixelSizenmEditField.Value = config.params.pixelSize;
            app.ResolutionpxEditField.Value = config.params.resolutionPx;
            app.ResolutionnmEditField.Value = app.ResolutionpxEditField.Value * app.PixelSizenmEditField.Value;

            app.EstimateGainOffsetCheckBox.Value = config.params.estimateGainOffset;
            app.GainEditField.Value = config.params.gain;
            app.OffsetEditField.Value = config.params.offset;

            app.ThresholdEditField.Value = config.params.threshold;
            app.EstimateEmitterIntensityThresholdCheckBox.Value = config.params.estimateThreshold;

            app.ProcessFrameRangeCheckBox.Value = config.params.range.enabled;
            app.StartEditField.Value            = config.params.range.start;
            app.EndEditField.Value              = config.params.range.finale;
            app.ProcessFrameRangeCheckBoxValueChanged("placeholder event");
            
            app.ConvertIntensitytoPhotonCountCheckBox.Value = config.preprocess.usePhotonCount;
            app.BackgroundRemovalDropDown.Value = config.preprocess.backgroundRemoval;
            app.InterFrameDifferenceCheckBox.Value = config.preprocess.interFrameDifference;
            
            app.ScaleEditField.Value = config.process.scale;
            app.UseGPUCheckBox.Value             = config.process.useGPU;

            tabs = app.ProcessingMethodTabGroup.Children;
            for i = 1:size(tabs, 1)
                tab = tabs(i);
                if isequal(tab.Title, config.process.method)
                    app.ProcessingMethodTabGroup.SelectedTab = tab;
                    break;
                end
            end
            
            app.TemporalAnalysisDropDown.Value = config.process.extraDense.temporal;
            app.InterpolationDropDown.Value = config.process.extraDense.interpolation;
            
            app.LocalizationDropDown.Value       = config.process.sparse.localization;
            app.AIMDriftCorrectionCheckBox.Value = config.process.sparse.aim;
            app.AIMTrackIntervalEditField.Value  = config.process.sparse.aimTrackInterval;
            
            app.AutoAdjustContrastCheckBox.Value = config.postprocess.adjustContrast;   

            app.epsilonEditField.Value = config.dbscan.epsilon;
            app.minptsEditField.Value = config.dbscan.minpts;

            app.SaveEstimatedBackgroundCheckBox.Value = config.log.saveBackground;

            app.TIFFCheckBox.Value = config.filetype.tiff;
            app.DCIMGCheckBox.Value = config.filetype.dcimg;
        end

        function config = UI2Config(app)
            config.params.decorr = app.EstimateInputResolutionCheckBox.Value;
            config.params.pixelSize = app.PixelSizenmEditField.Value;
            config.params.resolutionPx = app.ResolutionpxEditField.Value;

            config.params.estimateGainOffset = app.EstimateGainOffsetCheckBox.Value;
            config.params.gain = app.GainEditField.Value;
            config.params.offset = app.OffsetEditField.Value;

            config.params.estimateThreshold = app.EstimateEmitterIntensityThresholdCheckBox.Value;
            config.params.threshold = app.ThresholdEditField.Value;

            config.params.range.enabled = app.ProcessFrameRangeCheckBox.Value;
            config.params.range.start   = app.StartEditField.Value;
            config.params.range.finale  = app.EndEditField.Value;

            config.preprocess.usePhotonCount = app.ConvertIntensitytoPhotonCountCheckBox.Value;
            config.preprocess.backgroundRemoval = app.BackgroundRemovalDropDown.Value;
            config.preprocess.interFrameDifference = app.InterFrameDifferenceCheckBox.Value;
            
            config.process.scale = app.ScaleEditField.Value;
            config.process.method = app.ProcessingMethodTabGroup.SelectedTab.Title;
            config.process.useGPU = app.UseGPUCheckBox.Value;

            config.process.extraDense.temporal = app.TemporalAnalysisDropDown.Value;
            config.process.extraDense.interpolation = app.InterpolationDropDown.Value;
            
            config.process.sparse.localization = app.LocalizationDropDown.Value;
            config.process.sparse.aim = app.AIMDriftCorrectionCheckBox.Value;
            config.process.sparse.aimTrackInterval = app.AIMTrackIntervalEditField.Value;

            config.postprocess.adjustContrast = app.AutoAdjustContrastCheckBox.Value;

            config.dbscan.epsilon = app.epsilonEditField.Value;
            config.dbscan.minpts = app.minptsEditField.Value;

            config.log.saveBackground = app.SaveEstimatedBackgroundCheckBox.Value;

            config.filetype.tiff = app.TIFFCheckBox.Value;
            config.filetype.dcimg = app.DCIMGCheckBox.Value;
        end

        %% Result UI

        function UpdateLocalizationPanel(app)
            n_loc = numel(app.localizations.X);

            if n_loc > 0
                app.LocalizationNumberEditField.Value = n_loc;
                app.ClusterScatterPlotAxes.XLim = [0, app.imageLoader.width];
                app.ClusterScatterPlotAxes.YLim = [0, app.imageLoader.height];
                scatter(app.ClusterScatterPlotAxes, ...
                    app.localizations.Y, ...
                    app.imageLoader.height - app.localizations.X, ...
                    '.');
            end

            app.clusterId = [];
        end

        function UpdateDriftPanel(app)
            cla(app.DriftAxes);
            if app.config.process.sparse.aim && app.config.process.method == "Localization"
                hold(app.DriftAxes, 'on');
                plot(app.DriftAxes, app.drift(:,1), 'DisplayName', 'Drift X');
                plot(app.DriftAxes, app.drift(:,2), 'DisplayName', 'Drift Y');
                legend(app.DriftAxes, 'show');
                hold(app.DriftAxes, 'off');
            end
        end

        %% Result Folder
        function UpdateResultFolder(app, newFolder)
            app.resultFolder = newFolder;
            app.OpenResultFolderButton.Enable = "off";
        end

        function CreateResultFolder(app)
            if ~exist(app.resultFolder, 'dir')  % Check if the folder does not exist
                mkdir(app.resultFolder);       % Create the folder
            end
            app.OpenResultFolderButton.Enable = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get the folder of the running .mlapp file
            appPath = mfilename('fullpath');
            appFolder = fileparts(appPath);
            
            % Add that folder (or its subfolders) to path
            
            % addpath(genpath(fullfile(appFolder, 'Functions')));
            % addpath(genpath(fullfile(appFolder, 'ThirdParty')));
            
            disableDefaultInteractivity(app.ClusterScatterPlotAxes);

            % Load last used config
            conf = LoadConfigFromHomeDir();
            if ~isempty(conf)
                app.config = conf;
                try
                    app.Config2UI(conf);
                catch e
                    uialert(app.OmniSTORMUIFigure, ...
                        sprintf("Error in Config2UI:\n%s", e.message), ...
                        'Error loading saved config.');
                end
            end

            if gpuDeviceCount > 0
                app.UseGPUCheckBox.Value = true;
            else
                app.UseGPUCheckBox.Value = false;
                app.UseGPUCheckBox.Enable = "off";
            end

            app.imageLoader = OmniImageLoader();
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Read image stack
            filterspec = {'*.tif;*.dcimg'};
            [file, path] = uigetfile(filterspec, 'Select Image Stack to Open', app.lastOpenedPath);
            figure(app.OmniSTORMUIFigure);
            if isequal(file, 0) && isequal(path, 0)
                return
            end

            app.lastOpenedPath = path;

            progressdlg = uiprogressdlg(app.OmniSTORMUIFigure, Title="Please Wait", Message="Loading image stack");
            progressdlg.Value = 0.1;
            
            imagefile = [path file];

            app.imageLoader.openImage(imagefile);
            
            progressdlg.Value = 0.5;
            progressdlg.Message = "Estimating Parameters";

            app.LoadImagePostProcess();

            close(progressdlg);
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            value = app.FrameSpinner.Value;
            app.FrameSlider.Value = round(value);
            app.ReadAndDrawInputFrame();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunSuperResolutionPipeline();
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            [file, path] = uiputfile('*.tif', 'Save as TIFF', 'super-resolution-image.tif');
            figure(app.OmniSTORMUIFigure);
            if isequal(file, 0) && isequal(path, 0)
                return
            end

            save_tiff(app.processedImage, [path file]);
        end

        % Value changed function: ResolutionpxEditField
        function ResolutionpxEditFieldValueChanged(app, event)
            value = app.ResolutionpxEditField.Value;
            app.ResolutionnmEditField.Value = value * app.PixelSizenmEditField.Value;
        end

        % Value changed function: PixelSizenmEditField
        function PixelSizenmEditFieldValueChanged(app, event)
            value = app.PixelSizenmEditField.Value;
            app.ResolutionnmEditField.Value = value * app.ResolutionpxEditField.Value;
        end

        % Value changed function: ResolutionnmEditField
        function ResolutionnmEditFieldValueChanged(app, event)
            value = app.ResolutionnmEditField.Value;
            app.ResolutionpxEditField.Value =  value / app.PixelSizenmEditField.Value;
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            [file, path] = uiputfile('*.json', 'Save config', 'config.json');
            figure(app.OmniSTORMUIFigure);

            if isequal(file, 0) && isequal(path, 0)
                return
            end
            conf = app.UI2Config();
            writestruct(conf, [path file], PrettyPrint=true);
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            filterspec = {'*.json;'};
            [file, path] = uigetfile(filterspec);
            figure(app.OmniSTORMUIFigure);
            if isequal(file, 0) && isequal(path, 0)
                return
            end

            configFilename = [path file];
            conf = readstruct(configFilename);
            app.Config2UI(conf);
        end

        % Button pushed function: ContrastButtonSR
        function ContrastButtonSRPushed(app, event)
            imcontrast(app.SRImageAxes);
        end

        % Value changed function: EstimateInputResolutionCheckBox
        function EstimateInputResolutionCheckBoxValueChanged(app, event)
            value = app.EstimateInputResolutionCheckBox.Value;
            if value == true
                app.ResolutionnmEditField.Editable = "off";
                app.ResolutionpxEditField.Editable = "off";
            elseif value == false
                app.ResolutionnmEditField.Editable = "on";
                app.ResolutionpxEditField.Editable = "on";
            end
        end

        % Button pushed function: ContrastButtonInput
        function ContrastButtonInputPushed(app, event)
            imcontrast(app.InputImageAxes);
            msgbox('Adjust the contrast and close the window. DO NOT click "Adjust Data"', 'README');
        end

        % Button pushed function: BatchFolderButton
        function BatchFolderButtonPushed(app, event)
            selpath = uigetdir();
            figure(app.OmniSTORMUIFigure);
            if isequal(selpath, 0)
                return
            end
            app.LaunchBatchFolder(selpath);
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            value = event.Value;
            app.FrameSpinner.Value = round(value);
            app.ReadAndDrawInputFrame();
        end

        % Value changed function: EstimateGainOffsetCheckBox
        function EstimateGainOffsetCheckBoxValueChanged(app, event)
            value = app.EstimateGainOffsetCheckBox.Value;
            if value == true
                app.GainEditField.Editable = "off";
                app.OffsetEditField.Editable = "off";
            elseif value == false
                app.GainEditField.Editable = "on";
                app.OffsetEditField.Editable = "on";
            end
        end

        % Value changed function: EstimateEmitterIntensityThresholdCheckBox
        function EstimateEmitterIntensityThresholdCheckBoxValueChanged(app, event)
            value = app.EstimateEmitterIntensityThresholdCheckBox.Value;
            if value == true
                app.ThresholdEditField.Editable = "off";
            elseif value == false
                app.ThresholdEditField.Editable = "on";
            end
        end

        % Button pushed function: LoadMultiFileImageButton
        function LoadMultiFileImageButtonPushed(app, event)
            % Read image stack
            selpath = uigetdir(app.lastOpenedPath, 'Select Image Folder to Open');
            figure(app.OmniSTORMUIFigure);
            if isequal(selpath, 0)
                return
            end

            app.lastOpenedPath = selpath;

            progressdlg = uiprogressdlg(app.OmniSTORMUIFigure, Title="Please Wait", Message="Loading image stack");
            progressdlg.Value = 0.1;
            
            app.config = app.UI2Config();
            ext_name_list = GetImageFileExtName(app.config);
            app.imageLoader.openImageFolder(selpath, ext_name_list);
            

            progressdlg.Value = 0.5;
            progressdlg.Message = "Estimating Parameters";
            
            app.LoadImagePostProcess();

            close(progressdlg);
        end

        % Button pushed function: RunClusteringButton
        function RunClusteringButtonPushed(app, event)
            app.RunClusteringButton.Enable = "off";
            drawnow nocallbacks
            
            n_loc = numel(app.localizations.X);

            if n_loc > 0
                dataPoints = [app.localizations.X app.localizations.Y];
                app.clusterId = dbscan(dataPoints, app.config.dbscan.epsilon, app.config.dbscan.minpts);
                app.LocalizationNumberEditField.Value = n_loc;
                app.ClusterScatterPlotAxes.XLim = [0, app.imageLoader.width];
                app.ClusterScatterPlotAxes.YLim = [0, app.imageLoader.height];
                gscatter(app.ClusterScatterPlotAxes, ...
                    app.localizations.Y, ...
                    app.imageLoader.height - app.localizations.X, ...
                    app.clusterId);
            end

            clusterSavePath = fullfile(app.resultFolder, 'localization-clusters.csv');
            app.SaveLocalizationClusters(clusterSavePath);

            app.RunClusteringButton.Enable = "on"; 
        end

        % Value changed function: OutputFolderEditField
        function OutputFolderEditFieldValueChanged(app, event)
            app.UpdateResultFolder(app.OutputFolderEditField.Value);
        end

        % Button pushed function: BrowseResultFolderButton
        function BrowseResultFolderButtonPushed(app, event)
            selpath = uigetdir(app.lastOpenedPath, 'Select a Folder Containing Image Folders');
            figure(app.OmniSTORMUIFigure);
            if isequal(selpath, 0)
                return
            end

            app.lastOpenedPath = selpath;

            app.OutputFolderEditField.Value = selpath;
            app.UpdateResultFolder(selpath);
        end

        % Button pushed function: OpenResultFolderButton
        function OpenResultFolderButtonPushed(app, event)
            winopen(app.resultFolder);
        end

        % Button pushed function: FRCRunButton
        function FRCRunButtonPushed(app, event)
            app.RunFRC();
        end

        % Value changed function: ProcessFrameRangeCheckBox
        function ProcessFrameRangeCheckBoxValueChanged(app, event)
            value = app.ProcessFrameRangeCheckBox.Value;
            if value
                app.StartEditField.Enable = "on";
                app.EndEditField.Enable   = "on";
            else
                app.StartEditField.Enable = "off";
                app.EndEditField.Enable   = "off";
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.OmniSTORMUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {771, 771};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {268, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OmniSTORMUIFigure = uifigure('Visible', 'off');
            app.OmniSTORMUIFigure.Name = 'OmniSTORM';
            app.OmniSTORMUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.OmniSTORMUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PreProcessingPanel = uipanel(app.LeftPanel);
            app.PreProcessingPanel.Title = 'Pre-Processing';
            app.InterFrameDifferenceCheckBox = uicheckbox(app.PreProcessingPanel);
            app.InterFrameDifferenceCheckBox.Tooltip = {'Compute inter-frame differnce to increase number of localizations when frame number is small. If frame number > 100 it will be very slow, and the checkbox will be disabled.'};
            app.InterFrameDifferenceCheckBox.Text = 'Inter-Frame Difference';
            app.ConvertIntensitytoPhotonCountCheckBox = uicheckbox(app.PreProcessingPanel);
            app.ConvertIntensitytoPhotonCountCheckBox.Enable = 'off';
            app.ConvertIntensitytoPhotonCountCheckBox.Tooltip = {'Use photon count instead of pixel intensity in super-resolution processing. Photon count = (intensity - offset) / gain'};
            app.ConvertIntensitytoPhotonCountCheckBox.Text = 'Convert Intensity to Photon Count';
            app.BackgroundRemovalDropDownLabel = uilabel(app.PreProcessingPanel);
            app.BackgroundRemovalDropDownLabel.Text = 'Background Removal';
            app.BackgroundRemovalDropDown = uidropdown(app.PreProcessingPanel);
            app.BackgroundRemovalDropDown.Items = {'None', 'Temporal Min', 'WindSTORM'};
            app.BackgroundRemovalDropDown.Value = 'Temporal Min';
            app.SaveEstimatedBackgroundCheckBox = uicheckbox(app.PreProcessingPanel);
            app.SaveEstimatedBackgroundCheckBox.Text = 'Save Estimated Background';
            app.ProcessingPanel = uipanel(app.LeftPanel);
            app.ProcessingPanel.Title = 'Processing';
            app.ProcessingMethodTabGroup = uitabgroup(app.ProcessingPanel);
            app.LocalizationTab = uitab(app.ProcessingMethodTabGroup);
            app.LocalizationTab.Title = 'Localization';
            app.LocalizationDropDownLabel = uilabel(app.LocalizationTab);
            app.LocalizationDropDownLabel.Text = 'Localization';
            app.LocalizationDropDown = uidropdown(app.LocalizationTab);
            app.LocalizationDropDown.Items = {'Sonic', 'WindSTORM'};
            app.LocalizationDropDown.Value = 'Sonic';
            app.AIMDriftCorrectionCheckBox = uicheckbox(app.LocalizationTab);
            app.AIMDriftCorrectionCheckBox.Text = 'AIM Drift Correction';
            app.AIMTrackIntervalEditFieldLabel = uilabel(app.LocalizationTab);
            app.AIMTrackIntervalEditFieldLabel.Text = 'AIM Track Interval';
            app.AIMTrackIntervalEditField = uieditfield(app.LocalizationTab, 'numeric');
            app.AIMTrackIntervalEditField.RoundFractionalValues = 'on';
            app.AIMTrackIntervalEditField.Value = 200;
            app.DeconvolutionTab = uitab(app.ProcessingMethodTabGroup);
            app.DeconvolutionTab.Title = 'Deconvolution';
            app.TemporalAnalysisDropDownLabel = uilabel(app.DeconvolutionTab);
            app.TemporalAnalysisDropDownLabel.Text = 'Temporal Analysis';
            app.TemporalAnalysisDropDown = uidropdown(app.DeconvolutionTab);
            app.TemporalAnalysisDropDown.Items = {'mean', 'std'};
            app.TemporalAnalysisDropDown.Value = 'std';
            app.InterpolationDropDownLabel = uilabel(app.DeconvolutionTab);
            app.InterpolationDropDownLabel.Text = 'Interpolation';
            app.InterpolationDropDown = uidropdown(app.DeconvolutionTab);
            app.InterpolationDropDown.Items = {'bicubic', 'fourier'};
            app.InterpolationDropDown.Value = 'bicubic';
            app.ScaleEditFieldLabel = uilabel(app.ProcessingPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.ProcessingPanel, 'numeric');
            app.ScaleEditField.Limits = [1 Inf];
            app.ScaleEditField.Value = 4;
            app.UseGPUCheckBox = uicheckbox(app.ProcessingPanel);
            app.UseGPUCheckBox.Tooltip = {'OmniSTORM will use GPU to speed up localization when all of the following conditions are met:'; '1. This box is checked'; '2. The localization method is ''Sonic'''; '3. Your device has a GPU'};
            app.UseGPUCheckBox.Text = 'Use GPU';
            app.UseGPUCheckBox.Value = true;
            app.PostProcessingPanel = uipanel(app.LeftPanel);
            app.PostProcessingPanel.Title = 'Post-Processing';
            app.AutoAdjustContrastCheckBox = uicheckbox(app.PostProcessingPanel);
            app.AutoAdjustContrastCheckBox.Tooltip = {'Automatically adjust the constract of the result image by normalizing the intensity. Uncheck this if you want to keep the original intensity produced by localization.'};
            app.AutoAdjustContrastCheckBox.Text = 'Auto Adjust Contrast';
            app.AutoAdjustContrastCheckBox.Value = true;
            app.SaveConfigButton = uibutton(app.LeftPanel, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.LoadConfigButton = uibutton(app.LeftPanel, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.ParameterSetupPanel = uipanel(app.LeftPanel);
            app.ParameterSetupPanel.Title = 'Parameter Setup';
            app.EstimateInputResolutionCheckBox = uicheckbox(app.ParameterSetupPanel);
            app.EstimateInputResolutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @EstimateInputResolutionCheckBoxValueChanged, true);
            app.EstimateInputResolutionCheckBox.Tooltip = {'Estimate input resolution using decorrelation method. The estimated resolution will be used to estimate PSF during super-resolution.'};
            app.EstimateInputResolutionCheckBox.Text = 'Estimate Input Resolution';
            app.EstimateInputResolutionCheckBox.Value = true;
            app.ResolutionpxLabel = uilabel(app.ParameterSetupPanel);
            app.ResolutionpxLabel.Text = 'Resolution (px)';
            app.ResolutionpxEditField = uieditfield(app.ParameterSetupPanel, 'numeric');
            app.ResolutionpxEditField.ValueDisplayFormat = '%.2f';
            app.ResolutionpxEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionpxEditFieldValueChanged, true);
            app.ResolutionpxEditField.Editable = 'off';
            app.ResolutionnmLabel = uilabel(app.ParameterSetupPanel);
            app.ResolutionnmLabel.Text = 'Resolution (nm)';
            app.ResolutionnmEditField = uieditfield(app.ParameterSetupPanel, 'numeric');
            app.ResolutionnmEditField.ValueDisplayFormat = '%.2f';
            app.ResolutionnmEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionnmEditFieldValueChanged, true);
            app.ResolutionnmEditField.Editable = 'off';
            app.PixelSizenmEditFieldLabel = uilabel(app.ParameterSetupPanel);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size (nm)';
            app.PixelSizenmEditField = uieditfield(app.ParameterSetupPanel, 'numeric');
            app.PixelSizenmEditField.ValueDisplayFormat = '%.2f';
            app.PixelSizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizenmEditFieldValueChanged, true);
            app.PixelSizenmEditField.Value = 100;
            app.ThresholdEditFieldLabel = uilabel(app.ParameterSetupPanel);
            app.ThresholdEditFieldLabel.Tooltip = {'Pixel intensity less than threshold will be suppressed  to 0.'};
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ParameterSetupPanel, 'numeric');
            app.ThresholdEditField.Tooltip = {''};
            app.ThresholdEditField.Value = 100;
            app.ProcessFrameRangeCheckBox = uicheckbox(app.ParameterSetupPanel);
            app.ProcessFrameRangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcessFrameRangeCheckBoxValueChanged, true);
            app.ProcessFrameRangeCheckBox.Text = 'Process Frame Range';
            app.StartEditFieldLabel = uilabel(app.ParameterSetupPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.ParameterSetupPanel, 'numeric');
            app.StartEditField.RoundFractionalValues = 'on';
            app.StartEditField.Enable = 'off';
            app.EndEditFieldLabel = uilabel(app.ParameterSetupPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.ParameterSetupPanel, 'numeric');
            app.EndEditField.RoundFractionalValues = 'on';
            app.EndEditField.Enable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.ImageDisplayTabGroup = uitabgroup(app.RightPanel);
            app.InputImageStackTab = uitab(app.ImageDisplayTabGroup);
            app.InputImageStackTab.Title = 'Input Image Stack';
            app.InputImageAxes = uiaxes(app.InputImageStackTab);
            app.InputImageAxes.XTick = [];
            app.InputImageAxes.XTickLabel = '';
            app.InputImageAxes.YTick = [];
            colormap(app.InputImageAxes, 'gray')
            app.InputImageParamPanel = uipanel(app.InputImageStackTab);
            app.FrameSpinner = uispinner(app.InputImageParamPanel);
            app.FrameSpinner.ValueDisplayFormat = '%d';
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinner.Enable = 'off';
            app.FrameSliderLabel = uilabel(app.InputImageParamPanel);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.InputImageParamPanel);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.Enable = 'off';
            app.InformationPanel = uipanel(app.InputImageStackTab);
            app.InformationPanel.Title = 'Information';
            app.TostartclickLoadImageorLoadMultiFileImageLabel = uilabel(app.InformationPanel);
            app.TostartclickLoadImageorLoadMultiFileImageLabel.Text = 'To start, click "Load Image" or "Load Multi-File Image".';
            app.ContrastButtonInput = uibutton(app.InformationPanel, 'push');
            app.ContrastButtonInput.ButtonPushedFcn = createCallbackFcn(app, @ContrastButtonInputPushed, true);
            app.ContrastButtonInput.Text = 'Adjust Contrast';
            app.EstimateGainOffsetCheckBox = uicheckbox(app.InformationPanel);
            app.EstimateGainOffsetCheckBox.ValueChangedFcn = createCallbackFcn(app, @EstimateGainOffsetCheckBoxValueChanged, true);
            app.EstimateGainOffsetCheckBox.Enable = 'off';
            app.EstimateGainOffsetCheckBox.Visible = 'off';
            app.EstimateGainOffsetCheckBox.Text = 'Estimate Gain/Offset';
            app.EstimateEmitterIntensityThresholdCheckBox = uicheckbox(app.InformationPanel);
            app.EstimateEmitterIntensityThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @EstimateEmitterIntensityThresholdCheckBoxValueChanged, true);
            app.EstimateEmitterIntensityThresholdCheckBox.Enable = 'off';
            app.EstimateEmitterIntensityThresholdCheckBox.Visible = 'off';
            app.EstimateEmitterIntensityThresholdCheckBox.Text = 'Estimate Emitter Intensity Threshold';
            app.GainEditFieldLabel = uilabel(app.InformationPanel);
            app.GainEditFieldLabel.Enable = 'off';
            app.GainEditFieldLabel.Visible = 'off';
            app.GainEditFieldLabel.Tooltip = {'Digital count (intensity) / photon count'};
            app.GainEditFieldLabel.Text = 'Gain ';
            app.GainEditField = uieditfield(app.InformationPanel, 'numeric');
            app.GainEditField.ValueDisplayFormat = '%.2f';
            app.GainEditField.Enable = 'off';
            app.GainEditField.Visible = 'off';
            app.GainEditField.Tooltip = {''};
            app.GainEditField.Value = 1;
            app.OffsetEditFieldLabel = uilabel(app.InformationPanel);
            app.OffsetEditFieldLabel.Enable = 'off';
            app.OffsetEditFieldLabel.Visible = 'off';
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.InformationPanel, 'numeric');
            app.OffsetEditField.ValueDisplayFormat = '%.2f';
            app.OffsetEditField.Enable = 'off';
            app.OffsetEditField.Visible = 'off';
            app.SuperResolutionImageTab = uitab(app.ImageDisplayTabGroup);
            app.SuperResolutionImageTab.Title = 'Super-Resolution Image';
            app.SRImageAxes = uiaxes(app.SuperResolutionImageTab);
            app.SRImageAxes.XTick = [];
            app.SRImageAxes.XTickLabel = '';
            app.SRImageAxes.YTick = [];
            colormap(app.SRImageAxes, 'gray')
            app.SRImageParamPanel = uipanel(app.SuperResolutionImageTab);
            app.SRImageParamPanel.Title = 'Operations';
            app.SaveImageButton = uibutton(app.SRImageParamPanel, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.ContrastButtonSR = uibutton(app.SRImageParamPanel, 'push');
            app.ContrastButtonSR.ButtonPushedFcn = createCallbackFcn(app, @ContrastButtonSRPushed, true);
            app.ContrastButtonSR.Text = 'Adjust Contrast';
            app.LocalizationsTab = uitab(app.ImageDisplayTabGroup);
            app.LocalizationsTab.Title = 'Localizations';
            app.ClusterScatterPlotAxes = uiaxes(app.LocalizationsTab);
            app.ClusterScatterPlotAxes.XTick = [];
            app.ClusterScatterPlotAxes.XTickLabel = '';
            app.ClusterScatterPlotAxes.YTick = [];
            colormap(app.ClusterScatterPlotAxes, 'gray')
            app.ClusterOperationsPanel = uipanel(app.LocalizationsTab);
            app.ClusterOperationsPanel.Title = 'DBSCAN';
            app.epsilonEditFieldLabel = uilabel(app.ClusterOperationsPanel);
            app.epsilonEditFieldLabel.Text = 'epsilon';
            app.epsilonEditField = uieditfield(app.ClusterOperationsPanel, 'numeric');
            app.epsilonEditField.Value = 1;
            app.minptsEditFieldLabel = uilabel(app.ClusterOperationsPanel);
            app.minptsEditFieldLabel.Text = 'minpts';
            app.minptsEditField = uieditfield(app.ClusterOperationsPanel, 'numeric');
            app.minptsEditField.Value = 5;
            app.RunClusteringButton = uibutton(app.ClusterOperationsPanel, 'push');
            app.RunClusteringButton.ButtonPushedFcn = createCallbackFcn(app, @RunClusteringButtonPushed, true);
            app.RunClusteringButton.Text = 'Run Clustering';
            app.LocalizationsStatsPanel = uipanel(app.LocalizationsTab);
            app.LocalizationsStatsPanel.Title = 'Localizations';
            app.LocalizationNumberEditFieldLabel = uilabel(app.LocalizationsStatsPanel);
            app.LocalizationNumberEditFieldLabel.Text = 'Localization Number';
            app.LocalizationNumberEditField = uieditfield(app.LocalizationsStatsPanel, 'numeric');
            app.LocalizationNumberEditField.ValueDisplayFormat = '%d';
            app.LocalizationNumberEditField.Editable = 'off';
            app.FRCTab = uitab(app.ImageDisplayTabGroup);
            app.FRCTab.Title = 'FRC';
            app.FRCImage1Axes = uiaxes(app.FRCTab);
            title(app.FRCImage1Axes, 'FRC Image 1 (Odd Frames)')
            app.FRCImage1Axes.XTick = [];
            app.FRCImage1Axes.XTickLabel = '';
            app.FRCImage1Axes.YTick = [];
            colormap(app.FRCImage1Axes, 'gray')
            app.FRCImage2Axes = uiaxes(app.FRCTab);
            title(app.FRCImage2Axes, 'FRC Image 2 (Even Frames)')
            app.FRCImage2Axes.XTick = [];
            app.FRCImage2Axes.XTickLabel = '';
            app.FRCImage2Axes.YTick = [];
            colormap(app.FRCImage2Axes, 'gray')
            app.FRCCurveAxes = uiaxes(app.FRCTab);
            title(app.FRCCurveAxes, 'FRC Curve')
            xlabel(app.FRCCurveAxes, 'Frequency')
            app.FRCInfoPanel = uipanel(app.FRCTab);
            app.FRCInfoPanel.Title = 'Information';
            app.EstimatedResolutionpxEditField_2Label = uilabel(app.FRCInfoPanel);
            app.EstimatedResolutionpxEditField_2Label.Text = 'Estimated Resolution (px)';
            app.FRCResolutionpxEditField = uieditfield(app.FRCInfoPanel, 'numeric');
            app.FRCResolutionpxEditField.Editable = 'off';
            app.EstimatedResolutionpxEditField_2Label_2 = uilabel(app.FRCInfoPanel);
            app.EstimatedResolutionpxEditField_2Label_2.Text = 'Estimated Resolution (nm)';
            app.FRCResolutionnmEditField = uieditfield(app.FRCInfoPanel, 'numeric');
            app.FRCResolutionnmEditField.Editable = 'off';
            app.FRCRunButton = uibutton(app.FRCInfoPanel, 'push');
            app.FRCRunButton.ButtonPushedFcn = createCallbackFcn(app, @FRCRunButtonPushed, true);
            app.FRCRunButton.Enable = 'off';
            app.FRCRunButton.Text = 'Run FRC';
            app.DriftTab = uitab(app.ImageDisplayTabGroup);
            app.DriftTab.Title = 'Drift';
            app.DriftAxes = uiaxes(app.DriftTab);
            title(app.DriftAxes, 'Estimated Drift')
            xlabel(app.DriftAxes, 'Frame')
            ylabel(app.DriftAxes, 'Drift (px)')
            zlabel(app.DriftAxes, 'Z')
            app.LoadImageButton = uibutton(app.RightPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Tooltip = {'Load a single image stack file'};
            app.LoadImageButton.Text = 'Load Image';
            app.BatchFolderButton = uibutton(app.RightPanel, 'push');
            app.BatchFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BatchFolderButtonPushed, true);
            app.BatchFolderButton.Tooltip = {'Batch process a folder of subfolders. All images in a subfolder are used to construct a single super-resolution image.'};
            app.BatchFolderButton.Text = 'Batch Folder';
            app.RunButton = uibutton(app.RightPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.LoadMultiFileImageButton = uibutton(app.RightPanel, 'push');
            app.LoadMultiFileImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMultiFileImageButtonPushed, true);
            app.LoadMultiFileImageButton.Tooltip = {'Choose a directory, and combine all image files in the directory into one stack'};
            app.LoadMultiFileImageButton.Text = 'Load Multi-File Image';
            app.OutputFolderEditFieldLabel = uilabel(app.RightPanel);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder';
            app.OutputFolderEditField = uieditfield(app.RightPanel, 'text');
            app.OutputFolderEditField.ValueChangedFcn = createCallbackFcn(app, @OutputFolderEditFieldValueChanged, true);
            app.BrowseResultFolderButton = uibutton(app.RightPanel, 'push');
            app.BrowseResultFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseResultFolderButtonPushed, true);
            app.BrowseResultFolderButton.Text = 'Choose Folder';
            app.OpenResultFolderButton = uibutton(app.RightPanel, 'push');
            app.OpenResultFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenResultFolderButtonPushed, true);
            app.OpenResultFolderButton.Enable = 'off';
            app.OpenResultFolderButton.Text = 'Open';
            app.ImageFileTypeLabel = uilabel(app.RightPanel);
            app.ImageFileTypeLabel.Text = 'Image File Type: ';
            app.DCIMGCheckBox = uicheckbox(app.RightPanel);
            app.DCIMGCheckBox.Text = 'DCIMG';
            app.DCIMGCheckBox.Value = true;
            app.TIFFCheckBox = uicheckbox(app.RightPanel);
            app.TIFFCheckBox.Text = 'TIFF';
            app.TIFFCheckBox.Value = true;
            % Show the figure after all components are created
            app.OmniSTORMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OmniSTORM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OmniSTORMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OmniSTORMUIFigure)
        end
    end
end